classdef MonteCarloPiApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MonteCarloSimulationLabel     matlab.ui.control.Label
        RunSimulationButton           matlab.ui.control.Button
        EstimatorforPiEditField       matlab.ui.control.EditField
        EstimatorforPiEditFieldLabel  matlab.ui.control.Label
        RealValueofPiEditField        matlab.ui.control.EditField
        RealValueofPiEditFieldLabel   matlab.ui.control.Label
        RandomSeedEditField           matlab.ui.control.NumericEditField
        RandomSeedLabel               matlab.ui.control.Label
        NumberofPointsEditField       matlab.ui.control.NumericEditField
        NumberofPointsLabel           matlab.ui.control.Label
        PiEstimateAxes                matlab.ui.control.UIAxes
        DartBoardAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dartBoard % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.dartBoard = DartBoard(app.DartBoardAxes);
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)

            numThrows = app.NumberofPointsEditField.Value;
            app.dartBoard.ClearBoard;
            app.PiEstimateAxes.NextPlot = "replace";
            app.PiEstimateAxes.XLim = [0, numThrows];
            
            plot(app.PiEstimateAxes,[0,numThrows], [pi, pi]);
            app.PiEstimateAxes.NextPlot = "add";
            thrower = DartThrower(app.RandomSeedEditField.Value);
            
            for iDart = 1:numThrows
                app.dartBoard.AddDart(thrower.ThrowDart);
                piEstimate = 4*app.dartBoard.DartsInCircle/iDart;
                plot(app.PiEstimateAxes,iDart,piEstimate, 'b.')
            end

            app.EstimatorforPiEditField.Value = sprintf('%.4f',...
                piEstimate);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DartBoardAxes = uiaxes(app.UIFigure);
            zlabel(app.DartBoardAxes, 'Z')
            app.DartBoardAxes.XTick = [];
            app.DartBoardAxes.YTick = [];
            app.PiEstimateAxes = uiaxes(app.UIFigure);
            zlabel(app.PiEstimateAxes, 'Z')
            app.PiEstimateAxes.XLim = [0 1];
            app.PiEstimateAxes.YLim = [0 6.28318530717959];
            app.PiEstimateAxes.XTick = [];
            app.PiEstimateAxes.XTickLabel = '';
            app.PiEstimateAxes.YTick = 3.14159;
            app.PiEstimateAxes.YTickLabel = {'3.14159'};
            app.NumberofPointsLabel = uilabel(app.UIFigure);
            app.NumberofPointsLabel.Text = 'Number of Points?';
            app.NumberofPointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofPointsEditField.Limits = [0 100000];
            app.NumberofPointsEditField.RoundFractionalValues = 'on';
            app.NumberofPointsEditField.ValueDisplayFormat = '%d';
            app.NumberofPointsEditField.Value = 1000;
            app.RandomSeedLabel = uilabel(app.UIFigure);
            app.RandomSeedLabel.Text = 'Random Seed?';
            app.RandomSeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.RandomSeedEditField.Limits = [1 Inf];
            app.RandomSeedEditField.RoundFractionalValues = 'on';
            app.RandomSeedEditField.ValueDisplayFormat = '%d';
            app.RandomSeedEditField.Value = 1;
            app.RealValueofPiEditFieldLabel = uilabel(app.UIFigure);
            app.RealValueofPiEditFieldLabel.Text = 'Real Value of Pi:';
            app.RealValueofPiEditField = uieditfield(app.UIFigure, 'text');
            app.RealValueofPiEditField.Editable = 'off';
            app.RealValueofPiEditField.Value = '3.14159';
            app.EstimatorforPiEditFieldLabel = uilabel(app.UIFigure);
            app.EstimatorforPiEditFieldLabel.Text = 'Estimator for Pi';
            app.EstimatorforPiEditField = uieditfield(app.UIFigure, 'text');
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.MonteCarloSimulationLabel = uilabel(app.UIFigure);
            app.MonteCarloSimulationLabel.Text = 'Monte Carlo Simulation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MonteCarloPiApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end